classdef Hist_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Hist_GUI_UIFigure           matlab.ui.Figure
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SavetopimageMenu            matlab.ui.container.Menu
        SavebottomimageMenu         matlab.ui.container.Menu
        SavehistogramMenu           matlab.ui.container.Menu
        SaveimageMenu               matlab.ui.container.Menu
        SavedataMenu                matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        main_grid                   matlab.ui.container.GridLayout
        left_grid                   matlab.ui.container.GridLayout
        top_axes                    matlab.ui.control.UIAxes
        bottom_axes                 matlab.ui.control.UIAxes
        top_panel                   matlab.ui.container.Panel
        DisplayedchannelLabel       matlab.ui.control.Label
        top_graph_dropdown          matlab.ui.control.DropDown
        bottom_panel                matlab.ui.container.Panel
        DisplayedchannelLabel_2     matlab.ui.control.Label
        bottom_graph_dropdown       matlab.ui.control.DropDown
        right_grid                  matlab.ui.container.GridLayout
        histogram_axes              matlab.ui.control.UIAxes
        tab_group_hist              matlab.ui.container.TabGroup
        show_tab                    matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        DisplayoptionsPanel         matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        FittypeLabel                matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        bimodaldistributionButton   matlab.ui.control.RadioButton
        GaussiandistributionButton  matlab.ui.control.RadioButton
        ShowsingelGaussiansLabel    matlab.ui.control.Label
        ShowinitialguessLabel       matlab.ui.control.Label
        AddAnnotationLabel          matlab.ui.control.Label
        Switch_showInitial          matlab.ui.control.Switch
        Switch_AddAnno              matlab.ui.control.Switch
        Switch_shwoSingle           matlab.ui.control.Switch
        PlotrangeLabel              matlab.ui.control.Label
        NumberofbinsLabel           matlab.ui.control.Label
        GridLayout4                 matlab.ui.container.GridLayout
        EditField_BinNum            matlab.ui.control.NumericEditField
        GridLayout5                 matlab.ui.container.GridLayout
        EditField_rangeMin          matlab.ui.control.NumericEditField
        EditField_rangeMax          matlab.ui.control.NumericEditField
        toLabel                     matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        ColorLabel                  matlab.ui.control.Label
        DatachannelLabel            matlab.ui.control.Label
        HistChannelPopup            matlab.ui.control.DropDown
        GridLayout3                 matlab.ui.container.GridLayout
        startparameterTable         matlab.ui.control.Table
        Panel                       matlab.ui.container.Panel
        findstartparameterDropDownLabel  matlab.ui.control.Label
        findstartparameterDropDown  matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        HistogramfilteringTab       matlab.ui.container.Tab
        ImagefilteringTab           matlab.ui.container.Tab
        middle_grid                 matlab.ui.container.GridLayout
        flow_button                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        manipulation_flow = 0;  % This propertie determine the flow of manipulation. It is 0 for the flow "histogram to images" and 1 for the flow "images to histogram". It is changes by pressing the flow_button.
        findStartParameter = 'auto' % Status how the start parameters should be determined for the fits; automatic (auto) or by user input (user defined).
        DialogGUI = [] % Dialog GUI for asking user to provide the paths for the tsv and meta data file.
        tsv_struct = []; % Here the struct imported from the result tsv file is stored.
        HistColor = 'blue'; % Display color style of the histogram.
    end
    
    properties (Access = public)
        lasttsvPath = ''; % The last selected tsv-file path
        lastMetaDataPath = ''; % Last selected path to the file containing the meta data of the force map.
        EModul = [] % The loaded EModul values from the tsv file.
        EModul_filtered = [] % Filtered EModul values
        HistChannelNames = [] % Available channels which can be displayed as histogram
        HistObj = [] % Histogram object displayed in the histogram axes.
        Index = [] % Indices of loaded histogram values to connect the corresponding values to channel image pixel.
        FitObj = [] % Object of the fit on the histogram data.
        HistColorValue = []; % Current color code of the histogram face color.
        unimodal_fit = []; % Fit object of the unimodal fit on the histogram.
        unimodal_hist = [] % Histogram object for an unimodal histogram;
    end
    
    methods (Access = private)
        
        function app = switch_manipulation_flow(app)
            % Helper function for the switching of the manipulation flow
            % from "histogram to images" to "images to histogram" and vice
            % versa
            
            % Code
            % % % %             Enter code here                 % % % %
            
        end
        
        function hist_display_options_helpfcn(app,action)
        % hist_display_options_helpfcn is a helper function to activate or
        % deactivate the display options for the histogram in the "Histogram options"
        % tab below the histogram axes.
        %
        %   Example:
        %   hist_display_options_helpfcn(app,'activate');
        %   hist_display_options_helpfcn(app,'deactivate');
        
            % error handling for hist_display_options_helpfcn            
            if ~any(ismember({'activate';'deactivate'},action))
                error('The "action" input must be either "activate" or "deactivate"!')
            end
            
            % activate or deactivate histogram display option elements
            switch action
                case 'activate'
                    app.ColorLabel.Enable = 1;
                    app.ColorDropDown.Enable = 1;
                    app.DatachannelLabel.Enable = 1;
                    app.HistChannelPopup.Enable = 1;
                    app.PlotrangeLabel.Enable = 1;
                    app.EditField_rangeMin.Enable = 1;
                    app.toLabel.Enable = 1;
                    app.EditField_rangeMax.Enable = 1;
                    app.NumberofbinsLabel.Enable = 1;
                    app.EditField_BinNum.Enable = 1;
                    app.FittypeLabel.Enable = 1;
                    app.bimodaldistributionButton.Enable = 1;
                    app.GaussiandistributionButton.Enable = 1;
                    app.ShowinitialguessLabel.Enable = 1;
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'bimodal distribution')
                        app.ShowsingelGaussiansLabel.Enable = 1;
                        app.Switch_shwoSingle.Enable = 1;
                    end
                    app.AddAnnotationLabel.Enable = 1;
                    app.Switch_showInitial.Enable = 1;
                    app.AddAnnotationLabel.Enable = 1;
                    app.Switch_AddAnno.Enable = 1;
                    app.findstartparameterDropDown.Enable = 1;
                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                        app.startparameterTable.Enable = 'on';
                    end
                case 'deactivate'
                    app.ColorLabel.Enable = 0;
                    app.ColorDropDown.Enable = 0;
                    app.DatachannelLabel.Enable = 0;
                    app.HistChannelPopup.Enable = 0;
                    app.PlotrangeLabel.Enable = 0;
                    app.EditField_rangeMin.Enable = 0;
                    app.toLabel.Enable = 0;
                    app.EditField_rangeMax.Enable = 0;
                    app.NumberofbinsLabel.Enable = 0;
                    app.EditField_BinNum.Enable = 0;
                    app.FittypeLabel.Enable = 0;
                    app.bimodaldistributionButton.Enable = 0;
                    app.GaussiandistributionButton.Enable = 0;
                    app.ShowinitialguessLabel.Enable = 0;
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'bimodal distribution')
                        app.ShowsingelGaussiansLabel.Enable = 0;
                        app.Switch_shwoSingle.Enable = 0;
                    end
                    app.AddAnnotationLabel.Enable = 0;
                    app.Switch_showInitial.Enable = 0;
                    app.AddAnnotationLabel.Enable = 0;
                    app.Switch_AddAnno.Enable = 0;
                    app.findstartparameterDropDown.Enable = 0;
                    app.startparameterTable.Enable = 'off';
            end
        end
        
        function manage_histogram_helpfcn(app,action,specifier)
            % implemented actions with corresponding specifier:
            %   updateFigure    ->  Color, PlotRange, BinNum, FitType, ShowSingle, ShowInitial, AddAnnotation
            %   updateValues    ->  EModul
            %   draw            ->  Hist, Fit
            %   fit             ->  bimodal, unimodal
            
            % error handling for  maage_histogram_helpfcn
            
            valid_actions = {'updateFigure';...
                             'updateValues';...
                             'draw';...
                             'fit'};
                         
            updateFigureSpecifier = {'Color';...
                                     'PlotRange';...
                                     'BinNum';...
                                     'FitType';...
                                     'ShowSingle';...
                                     'ShowInitial'; ...
                                     'AddAnnotation'};
                                 
            updateValuesSpecifier = {'EModul'};
            drawSpecifier = {'Hist';...
                             'Fit'};
                         
            fitSpecifier = {'bimodal';...
                            'unimodal'};
            
            if ~ischar(action) || ~ischar(specifier)
                error('Inputs for ''action'' and ''specifier'' must both be characters!')
            end
                            
                            
            if ~any(ismember(valid_actions,action))
                error('The input for the parameter "action" must be one of the following:\n''updateFigure''\n''updateValues''\n''draw''\n''fit''%s\n',' ');
            end
            
            switch action
                case 'updateFigure'
                    if ~any(ismember(updateFigureSpecifier,specifier))
                        error('With the action ''updateFigure'' just one of the following specifier is valid:\n''Color''\n''PlotRange\n''BinNum''\n''FitType''\n''ShowSingle''\n''ShowInitial''\n''AddAnnotation''%s\n',' ');
                    end
                case 'updateValues'
                    if ~any(ismember(updateValuesSpecifier,specifier))
                        error('With the action ''updateValues'' just one of the following specifier is valid:\n''EModul''\n%s\n',' ');
                    end
                case 'draw'
                    if ~any(ismember(drawSpecifier,specifier))
                        error('With the action ''draw'' just one of the following specifier is valid:\n''Hist''\n''Fit''%s\n',' ');
                    end
                case 'fit'
                    if ~any(ismember(fitSpecifier,specifier))
                        error('With the action ''fit'' just one of the following specifier is valid:\n''bimodal''\n''unimodal''%s\n',' ');
                    end
            end
            
            % main code of function
            switch action
                case 'updateFigure'
                    switch specifier
                        case 'Color'
                            switch app.HistColor
                                case 'blue'
                                    app.HistObj.hist.FaceColor = [0 .447 .741]; 
                                    app.HistColorValue = [0 .447 .741];
                                case 'red'
                                    app.HistObj.hist.FaceColor = [.9 .1 .1];
                                    app.HistColorValue = [.9 .1 .1];
                                case 'yellow'
                                    app.HistObj.hist.FaceColor = [.929 .694 .125];
                                    app.HistColorValue = [.929 .694 .125];
                                case 'green'
                                    app.HistObj.hist.FaceColor = [.466 .674 .188];
                                    app.HistColorValue = [.466 .674 .188];
                                case 'Custom'
                                    C = uisetcolor(app.HistObj.hist,'Select histogram color');
                                    app.HistColorValue = C;
                                    app.HistObj.hist.FaceColor = C;
                            end
                        case 'PlotRange'
                            
                        case 'BinNum'
                            
                        case 'FitType'
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                                        app.startparameterTable.Data = {'a1','';'E1','';'w1','';'a2','';'E2','';'w2',''};
                                    end
                                    
                                case 'Gaussian distribution'
                                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                                        app.startparameterTable.Data = {'a','';'E','';'w',''}; 
                                    end
                            end
                        case 'ShowSingle'
                            
                        case 'ShowInitial'
                            
                        case 'AddAnnotation'
                            
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''updateFigure'' ',specifier);                          
                    end
                case 'updateValues'
                    switch specifier
                        case 'EModul'
                            
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''updateValues'' ',specifier); 
                    end
                case 'draw'
                    switch specifier
                        case 'Hist'
                            app.HistObj = BimodalHistogram(app.EModul_filtered,[app.EditField_rangeMin.Value app.EditField_rangeMax.Value],app.EditField_BinNum.Value,'yes',app.histogram_axes);   
                        case 'Fit'
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    app.HistObj.plotFit(app.histogram_axes);
                                case 'Gaussian distribution'
                                    if isempty(app.unimodal_fit)
                                        error('You first need to call manage_histogram_helpfcn(app,''fit'',''unimodal'') to create the fit object!');
                                    end
                                    x_data = linspace(app.EditField_rangeMin.Value,app.EditField_rangeMax.Value,400);
                                    y_data = feval(app.unimodal_fit,x_data);
                                    hold(app.histogram_axes,'on')
                                    plot(app.histogram_axes,x_data,y_data,'-k','LineWidth',2);
                                    hold(app.histogram_axes,'off')
                            end
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''draw'' ',specifier); 
                    end
                case 'fit'
                    switch specifier
                        case 'bimodal'
                            switch app.findstartparameterDropDown.Value
                                case 'auto'
                                    app.HistObj = app.HistObj.doFit(app.histogram_axes);
                                case 'user defined'
                                    if any(isempty(app.startparameterTable.Data(:,2)))
                                        app.HistObj = app.HistObj.doFit(app.histogram_axes);
                                    else
                                        Start_p = cell2mat(app.startparameterTable.Data(:,2));
                                       app.HistObj = app.HistObj.doFit(Start_p','no');
                                    end
                            end
                        case 'unimodal'
                            switch app.findstartparameterDropDown.Value
                                case 'auto'
                                    [x_data,y_data] = prepareCurveData(app.HistObj.BinCenters,app.HistObj.BinCounts);
                                    [center_max,ind_max] = max(y_data);
                                    max_pos = x_data(ind_max);
                                    max_width = (max_pos - min(x_data))/2;
                                    app.unimodal_fit = fit(x_data,y_data,'gauss1','StartPoint',[center_max max_pos max_width]);
                                case 'user defined'
                                    [x_data,y_data] = prepareCurveData(app.HistObj.BinCenters,app.HistObj.BinCounts);
                                    if any(isempty(app.startparameterTable.Data(:,2)))
                                        app.unimodal_fit = fit(x_data,y_data,'gauss1');
                                    else
                                        Start_p = cell2mat(app.startparameterTable.Data(:,2));
                                        app.unimodal_fit = fit(x_data,y_data,'gauss1','StartPoint',Start_p');
                                    end
                            end
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''fit'' ',specifier);
                    end
            end
            
        end
    end
    
    methods (Access = public)
        
        function importResultsFile(app,file_path)
            %   importResultsFile is a function to import the results file
            %   generated after the dada processing of a force map with the
            %   CANTER Processing Tool.
            %
            %   As output this function provides a struct containing the
            %   result file data as N-by-1 arrays. The field names are the
            %   corresponding names of the underlying data, e.g. 'Young's
            %   modulus' or 'Curve Index'.
            %   
            %   As input, the function needs the path to the .tsv result
            %   file as a string.
            %
            %   Example:
            %   result_struct = importResultsFile('C:\Users\User_name\Results\result_file.tsv');
            %
            
            % error handling for importResultsFile
            if ~ischar(file_path)
                error('file_path must be of type character vector!')
            end
            
            if ~isfile(file_path)   
                error('File not found!\nInput for file_path must be a path to a existing file!%s\n',' ');
            end
            
            [~,~,f_ex] = fileparts(file_path);
            if ~strcmp(f_ex,'.tsv')
                error('Not a .tsv file!\nThis function can only read .tsv files!%s\n',' ')
            end
           
            % main code of function
            % Open tsv file in "read text" mode
            tsv_ID = fopen(file_path,"rt");
            % read first line and evaluate if the loaded tsv-file is valid
            header_line = fgetl(tsv_ID);
            header_names = strsplit(header_line,'\t');
            if ~(strcmp(header_names{1},'File_name') && strcmp(header_names{2},'Index'))
               uialert(app,'The tsv file you want to load is not a result file generated with the CANTER Processing Tool!','Invalid tsv file');
               fclose(tsv_ID);
               return;
            end
            % read remaining file
            N_arrays = length(header_names);
            formatSpec = '%s %d';
            for i=1:(N_arrays-2)
               formatSpec = [formatSpec ' %f']; 
            end
            tsv_arrays = textscan(tsv_ID,formatSpec,'Delimiter','\t');
            % close file ID
            fclose(tsv_ID);
            % create tsv struct
            tsv_struc = struct();
            for i=1:N_arrays
                if i == 1
                    tsv_struc.(header_names{i}) = string(tsv_arrays{i});
                else
                    tsv_struc.(header_names{i}) = tsv_arrays{i};
                end
            end
            % write tsv_struct to app property "tsv_struct"
            app.tsv_struct = tsv_struc;
            
            
        end
        
        function importFiles_helpFcn(app)
            % Load the tsv results file
            importResultsFile(app,app.lasttsvPath)        
                      
            % get channel names and load them into the HistChannelPopup
            ChannelNames = fieldnames(app.tsv_struct);
            app.HistChannelNames = ChannelNames(3:end);
            app.HistChannelPopup.Items = app.HistChannelNames;
                        
            % import histogram data and index from results struct  and select correct channel in
            % HistChannelPopup
            if any(ismember(app.HistChannelNames,'EModul'))
                app.EModul = app.tsv_struct.EModul;
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = 'EModul';
            elseif an(ismember(app.HistChannelNames,'E_s'))
                app.EModul = app.tsv_struct.E_s;
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = 'E_s';
            else
                else_name = app.HistChannelNames{1};
                app.EModul = app.tsv_struct.(else_name);
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = else_name;
            end
            if isfield(app.tsv_struct,'Index')
                app.Index = app.tsv_struct.Index;
            else
               app.Index = (1:length(app.EModul))';
            end
            
            % evaluate default display range for the histogram
            app.EditField_rangeMin.Value = min(app.EModul_filtered);
            app.EditField_rangeMax.Value = max(app.EModul_filtered);
            
            
            % Display loaded data
            manage_histogram_helpfcn(app,'draw','Hist');
            
            % add bimodal fit to histogram (default at loading)
            manage_histogram_helpfcn(app,'fit','bimodal');
                  
            
            % Enable all relevant elements
            hist_display_options_helpfcn(app,'activate');
        end
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.histogram_axes.Toolbar.Visible = 'off';
            app.top_axes.Toolbar.Visible = 'off';
            app.bottom_axes.Toolbar.Visible = 'off';
            
            drawnow;
            app.Hist_GUI_UIFigure.WindowState = 'maximized';
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            hist_display_options_helpfcn(app,'deactivate');
        end

        % Value changed function: flow_button
        function flow_buttonValueChanged(app, event)
            value = app.flow_button.Value;
            if value
                app.flow_button.Icon = 'button_1.png';
                app.manipulation_flow = 1;
            else
                app.flow_button.Icon = 'button_0.png';
                app.manipulation_flow = 0;
            end
            % app.top_graph_dropdown.Items = string(value); -> just for testing the
            % switch.
        end

        % Value changed function: findstartparameterDropDown
        function findstartparameterDropDownValueChanged(app, event)
            value = app.findstartparameterDropDown.Value;
            switch value
                case 'auto'
                    app.startparameterTable.Enable = 'off';
                    app.startparameterTable.Data = [];
                case 'user defined'
                    app.startparameterTable.Enable = 'on';
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            app.startparameterTable.Data = {'a1','';'E1','';'w1','';'a2','';'E2','';'w2',''};
                        case 'Gaussian distribution'
                            app.startparameterTable.Data = {'a','';'E','';'w',''};
                    end
            end
            app.findStartParameter = value;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open the GUI for selecting and loading the tsv- and
            % meta-data-file.
            Get_Paths_GUI(app,app.lasttsvPath,app.lastMetaDataPath);
            
           
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            value = app.ButtonGroup.SelectedObject.Text;            
            manage_histogram_helpfcn(app,'updateFigure','FitType');
            cla(app.histogram_axes);
            manage_histogram_helpfcn(app,'draw','Hist');
            switch value
                case 'bimodal distribution'
                    manage_histogram_helpfcn(app,'fit','bimodal')
                case 'Gaussian distribution'
                    manage_histogram_helpfcn(app,'fit','unimodal')
            end
            manage_histogram_helpfcn(app,'draw','Fit');
        end

        % Menu selected function: SavetopimageMenu
        function SavetopimageMenuSelected(app, event)
            keyboard
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.HistColor = value;
            manage_histogram_helpfcn(app,'updateFigure','Color');
        end

        % Value changed function: HistChannelPopup
        function HistChannelPopupValueChanged(app, event)
            value = app.HistChannelPopup.Value;
            app.EModul = app.tsv_struct.(value);
            % % %           In future, here we have to take care on chosen filters            % % %
            app.EModul_filtered = app.EModul;
            % draw new histogram
            cla(app.histogram_axes);
            app.EditField_rangeMin.Value = min(app.EModul_filtered);
            app.EditField_rangeMax.Value = max(app.EModul_filtered);
            manage_histogram_helpfcn(app,'draw','Hist');
            % calculate new fit
            switch app.ButtonGroup.SelectedObject.Text
                case 'bimodal distribution'
                    manage_histogram_helpfcn(app,'fit','bimodal')
                case 'Gaussian distribution'
                    manage_histogram_helpfcn(app.fit,'unimodal')
            end
            % plot fit result
            manage_histogram_helpfcn(app,'draw','Fit');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Hist_GUI_UIFigure = uifigure('Visible', 'off');
            app.Hist_GUI_UIFigure.Name = 'Hist_GUI_';
            app.OpenMenu = uimenu(app.Hist_GUI_UIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Hist_GUI_UIFigure);
            app.SaveMenu.Text = 'Save';
            app.SavetopimageMenu = uimenu(app.SaveMenu);
            app.SavetopimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetopimageMenuSelected, true);
            app.SavetopimageMenu.Text = 'Save top image';
            app.SavebottomimageMenu = uimenu(app.SaveMenu);
            app.SavebottomimageMenu.Text = 'Save bottom image';
            app.SavehistogramMenu = uimenu(app.SaveMenu);
            app.SavehistogramMenu.Text = 'Save histogram';
            app.SaveimageMenu = uimenu(app.SavehistogramMenu);
            app.SaveimageMenu.Text = 'Save image';
            app.SavedataMenu = uimenu(app.SavehistogramMenu);
            app.SavedataMenu.Text = 'Save data';
            app.HelpMenu = uimenu(app.Hist_GUI_UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.main_grid = uigridlayout(app.Hist_GUI_UIFigure);
            app.left_grid = uigridlayout(app.main_grid);
            app.top_axes = uiaxes(app.left_grid);
            title(app.top_axes, '')
            xlabel(app.top_axes, '')
            ylabel(app.top_axes, '')
            app.bottom_axes = uiaxes(app.left_grid);
            title(app.bottom_axes, '')
            xlabel(app.bottom_axes, '')
            ylabel(app.bottom_axes, '')
            app.top_panel = uipanel(app.left_grid);
            app.DisplayedchannelLabel = uilabel(app.top_panel);
            app.DisplayedchannelLabel.Text = 'Displayed channel';
            app.top_graph_dropdown = uidropdown(app.top_panel);
            app.top_graph_dropdown.Items = {'No Channel'};
            app.top_graph_dropdown.Value = 'No Channel';
            app.bottom_panel = uipanel(app.left_grid);
            app.DisplayedchannelLabel_2 = uilabel(app.bottom_panel);
            app.DisplayedchannelLabel_2.Text = 'Displayed channel';
            app.bottom_graph_dropdown = uidropdown(app.bottom_panel);
            app.bottom_graph_dropdown.Items = {'No channel'};
            app.bottom_graph_dropdown.Value = 'No channel';
            app.right_grid = uigridlayout(app.main_grid);
            app.histogram_axes = uiaxes(app.right_grid);
            title(app.histogram_axes, 'Histogram')
            xlabel(app.histogram_axes, '')
            ylabel(app.histogram_axes, '')
            app.tab_group_hist = uitabgroup(app.right_grid);
            app.show_tab = uitab(app.tab_group_hist);
            app.show_tab.Title = 'Histogram options';
            app.GridLayout2 = uigridlayout(app.show_tab);
            app.DisplayoptionsPanel = uipanel(app.GridLayout2);
            app.DisplayoptionsPanel.Title = 'Display options';
            app.GridLayout = uigridlayout(app.DisplayoptionsPanel);
            app.FittypeLabel = uilabel(app.GridLayout);
            app.FittypeLabel.Enable = 'off';
            app.FittypeLabel.Text = 'Fit type';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.bimodaldistributionButton = uiradiobutton(app.ButtonGroup);
            app.bimodaldistributionButton.Enable = 'off';
            app.bimodaldistributionButton.Text = 'bimodal distribution';
            app.bimodaldistributionButton.Value = true;
            app.GaussiandistributionButton = uiradiobutton(app.ButtonGroup);
            app.GaussiandistributionButton.Enable = 'off';
            app.GaussiandistributionButton.Text = 'Gaussian distribution';
            app.ShowsingelGaussiansLabel = uilabel(app.GridLayout);
            app.ShowsingelGaussiansLabel.Enable = 'off';
            app.ShowsingelGaussiansLabel.Text = 'Show singel Gaussians';
            app.ShowinitialguessLabel = uilabel(app.GridLayout);
            app.ShowinitialguessLabel.Enable = 'off';
            app.ShowinitialguessLabel.Text = 'Show initial guess';
            app.AddAnnotationLabel = uilabel(app.GridLayout);
            app.AddAnnotationLabel.Enable = 'off';
            app.AddAnnotationLabel.Text = 'Add Annotation';
            app.Switch_showInitial = uiswitch(app.GridLayout, 'slider');
            app.Switch_showInitial.Items = {'no', 'yes'};
            app.Switch_showInitial.Enable = 'off';
            app.Switch_showInitial.Value = 'no';
            app.Switch_AddAnno = uiswitch(app.GridLayout, 'slider');
            app.Switch_AddAnno.Items = {'no', 'yes'};
            app.Switch_AddAnno.Enable = 'off';
            app.Switch_AddAnno.Value = 'no';
            app.Switch_shwoSingle = uiswitch(app.GridLayout, 'slider');
            app.Switch_shwoSingle.Items = {'no', 'yes'};
            app.Switch_shwoSingle.Enable = 'off';
            app.Switch_shwoSingle.Value = 'yes';
            app.PlotrangeLabel = uilabel(app.GridLayout);
            app.PlotrangeLabel.Enable = 'off';
            app.PlotrangeLabel.Text = 'Plot range';
            app.NumberofbinsLabel = uilabel(app.GridLayout);
            app.NumberofbinsLabel.Enable = 'off';
            app.NumberofbinsLabel.Text = 'Number of bins';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.EditField_BinNum = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_BinNum.Limits = [1 Inf];
            app.EditField_BinNum.ValueDisplayFormat = '%d';
            app.EditField_BinNum.Enable = 'off';
            app.EditField_BinNum.Value = 50;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.EditField_rangeMin = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_rangeMin.Limits = [0 Inf];
            app.EditField_rangeMin.ValueDisplayFormat = '%.2g';
            app.EditField_rangeMin.Enable = 'off';
            app.EditField_rangeMax = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_rangeMax.Limits = [0 Inf];
            app.EditField_rangeMax.ValueDisplayFormat = '%.2g';
            app.EditField_rangeMax.Enable = 'off';
            app.EditField_rangeMax.Value = 300000;
            app.toLabel = uilabel(app.GridLayout5);
            app.toLabel.Enable = 'off';
            app.toLabel.Text = 'to';
            app.ColorDropDown = uidropdown(app.GridLayout);
            app.ColorDropDown.Items = {'blue', 'red', 'yellow', 'green', 'Custom'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Enable = 'off';
            app.ColorDropDown.Value = 'blue';
            app.ColorLabel = uilabel(app.GridLayout);
            app.ColorLabel.Enable = 'off';
            app.ColorLabel.Text = 'Color';
            app.DatachannelLabel = uilabel(app.GridLayout);
            app.DatachannelLabel.Enable = 'off';
            app.DatachannelLabel.Text = 'Data channel';
            app.HistChannelPopup = uidropdown(app.GridLayout);
            app.HistChannelPopup.Items = {};
            app.HistChannelPopup.ValueChangedFcn = createCallbackFcn(app, @HistChannelPopupValueChanged, true);
            app.HistChannelPopup.Enable = 'off';
            app.HistChannelPopup.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.startparameterTable = uitable(app.GridLayout3);
            app.startparameterTable.ColumnName = {'Parameter'; 'Start value'};
            app.startparameterTable.RowName = {''};
            app.startparameterTable.ColumnEditable = [false true];
            app.startparameterTable.Enable = 'off';
            app.Panel = uipanel(app.GridLayout3);
            app.findstartparameterDropDownLabel = uilabel(app.Panel);
            app.findstartparameterDropDownLabel.Enable = 'off';
            app.findstartparameterDropDownLabel.Text = 'find start parameter';
            app.findstartparameterDropDown = uidropdown(app.Panel);
            app.findstartparameterDropDown.Items = {'auto', 'user defined'};
            app.findstartparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @findstartparameterDropDownValueChanged, true);
            app.findstartparameterDropDown.Enable = 'off';
            app.findstartparameterDropDown.Value = 'auto';
            app.TabGroup = uitabgroup(app.right_grid);
            app.HistogramfilteringTab = uitab(app.TabGroup);
            app.HistogramfilteringTab.Title = 'Histogram filtering';
            app.ImagefilteringTab = uitab(app.TabGroup);
            app.ImagefilteringTab.Title = 'Image filtering';
            app.middle_grid = uigridlayout(app.main_grid);
            app.flow_button = uibutton(app.middle_grid, 'state');
            app.flow_button.ValueChangedFcn = createCallbackFcn(app, @flow_buttonValueChanged, true);
            app.flow_button.Icon = 'button_0.png';
            app.flow_button.Text = '';
            % Show the figure after all components are created
            app.Hist_GUI_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hist_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Hist_GUI_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Hist_GUI_UIFigure)
        end
    end
end